classdef digi2excel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TestLogButton  matlab.ui.control.Button
        TextArea       matlab.ui.control.TextArea
        TextAreaLabel  matlab.ui.control.Label
        Button         matlab.ui.control.Button
    end

    
    properties (Access = private)
        StrArray % for logging
    end
    
    methods (Access = private)
        
        function log_func(app, logStr)
            app.StrArray = [app.StrArray, {logStr}];
            app.TextArea.Value = app.StrArray;
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            className = class(app);  % 获取当前app的类名
            classPath = which(className);
            log_func(app, ['PATH:  ', classPath])

            a = uigetdir();
            log_func(app, ['PATH:  ', a])
            cd(a);
            
            log_func(app, ['CURRENT PATH:  ', pwd])
            digitalMicDataTool
        end

        % Callback function
        function TestLogButtonPushed(app, event)

        end

        % Button pushed function: TestLogButton
        function TestLogButtonPushed2(app, event)
            for i = 1:10
                log_func(app,'---*---');
                pause(0.1);
                log_func(app, char(string(i)));
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TestLogButton = uibutton(app.UIFigure, 'push');
            app.TestLogButton.ButtonPushedFcn = createCallbackFcn(app, @TestLogButtonPushed2, true);
            app.TestLogButton.Text = 'TestLog';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = digi2excel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end